classdef Toolbox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GeometricParametersPanel        matlab.ui.container.Panel
        BellowGeometryPanel             matlab.ui.container.Panel
        BellowNumberLabel               matlab.ui.control.Label
        BellowNumberEditField           matlab.ui.control.NumericEditField
        RadiusRatioLabel                matlab.ui.control.Label
        RadiusRatioEditField            matlab.ui.control.NumericEditField
        WallThicknesstmmLabel           matlab.ui.control.Label
        WallThicknesstmmEditField       matlab.ui.control.NumericEditField
        GeneralGeometryPanel            matlab.ui.container.Panel
        EffectiveLengthmmLabel          matlab.ui.control.Label
        EffectiveLengthmmEditField      matlab.ui.control.NumericEditField
        AverageRadiusmmLabel            matlab.ui.control.Label
        AverageRadiusmmEditField        matlab.ui.control.NumericEditField
        DesignUpdateButton              matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        MaterialPropertiesPanel         matlab.ui.container.Panel
        MaterialSelectionDropDownLabel  matlab.ui.control.Label
        MaterialSelectionDropDown       matlab.ui.control.DropDown
        DigitalMaterialDropDownLabel    matlab.ui.control.Label
        DigitalMaterialDropDown         matlab.ui.control.DropDown
        YoungsModuluskPaLabel           matlab.ui.control.Label
        YoungsModuluskPaEditField       matlab.ui.control.NumericEditField
        PossionRatioEditFieldLabel      matlab.ui.control.Label
        PossionRatioEditField           matlab.ui.control.NumericEditField
        Densitygcm3EditFieldLabel       matlab.ui.control.Label
        Densitygcm3EditField            matlab.ui.control.NumericEditField
        TheoreticalModelingPanel        matlab.ui.container.Panel
        AnalyzeResultButton             matlab.ui.control.Button
        PressureSliderkPaLabel          matlab.ui.control.Label
        PressureSliderkPa               matlab.ui.control.Slider
        EditField                       matlab.ui.control.NumericEditField
        CorrespondingAngularDeflectiondegreeLabel  matlab.ui.control.Label
        CorrespondingAngularDeflectiondegreeEditField  matlab.ui.control.NumericEditField
        Image                           matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        FEAModelingPanel                matlab.ui.container.Panel
        DeflectionAnlysisPanel          matlab.ui.container.Panel
        TargetPressurePkPaLabel         matlab.ui.control.Label
        TargetPressurePkPaEditField     matlab.ui.control.NumericEditField
        TargetAngularDeflectiondegreeLabel  matlab.ui.control.Label
        TargetAngularDeflectiondegreeEditField  matlab.ui.control.NumericEditField
        AnalyzeButton                   matlab.ui.control.Button
        AnalyzeButton_2                 matlab.ui.control.Button
        MeshElementSizeDropDownLabel    matlab.ui.control.Label
        MeshElementSizeDropDown         matlab.ui.control.DropDown
        ForceAnlysisPanel               matlab.ui.container.Panel
        TargetPressurePkPaLabel_2       matlab.ui.control.Label
        TargetPressurePkPaEditField_2   matlab.ui.control.NumericEditField
        AnalyzeButton_3                 matlab.ui.control.Button
        MeshElementSizeDropDown_2Label  matlab.ui.control.Label
        MeshElementSizeDropDown_2       matlab.ui.control.DropDown
        ExportGeometryButton            matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.BellowNumberLabel.Text = {'Bellow Number N'};
            app.RadiusRatioLabel.Text = {['Radius Ratio ' char(955)]};
            app.EffectiveLengthmmLabel.Text = {'Effective Length L [mm]'};
            app.AverageRadiusmmLabel.Text = {['Average Radius '  ['r', char(8344), ' [mm]']]};
            app.CorrespondingAngularDeflectiondegreeLabel.Text = {'Cooresponding Angular Deflection', [char(920) ' [degree]']};
            app.TargetAngularDeflectiondegreeLabel.Text = {'Target Angular Deflection', [char(920) ' [degree]']};

        end

        % Value changed function: EffectiveLengthmmEditField
        function EffectiveLengthmmEditFieldValueChanged(app, event)
            value = app.EffectiveLengthmmEditField.Value;

            
        end

        % Value changed function: AverageRadiusmmEditField
        function AverageRadiusmmEditFieldValueChanged(app, event)
            value = app.AverageRadiusmmEditField.Value;
            
        end

        % Value changed function: BellowNumberEditField
        function BellowNumberEditFieldValueChanged(app, event)
            value = app.BellowNumberEditField.Value;
            value = round(value);
        end

        % Value changed function: RadiusRatioEditField
        function RadiusRatioEditFieldValueChanged(app, event)
            value = app.RadiusRatioEditField.Value;
            
        end

        % Value changed function: MaterialSelectionDropDown
        function MaterialSelectionDropDownValueChanged(app, event)
            value = app.MaterialSelectionDropDown.Value;
            if value == "Agilus30"
                if app.DigitalMaterialDropDown.Value == "N/A"
                    app.YoungsModuluskPaEditField.Value = 450;
                elseif app.DigitalMaterialDropDown.Value == "DM40"
                    app.YoungsModuluskPaEditField.Value = 550.1;
                elseif app.DigitalMaterialDropDown.Value == "DM50"
                    app.YoungsModuluskPaEditField.Value = 571.5;
                elseif app.DigitalMaterialDropDown.Value == "DM60"
                    app.YoungsModuluskPaEditField.Value = 898.1;
                elseif app.DigitalMaterialDropDown.Value == "DM70"
                    app.YoungsModuluskPaEditField.Value = 1699.1;
                end
                app.PossionRatioEditField.Value = 0.49;
                app.Densitygcm3EditField.Value = 1.16;
            elseif app.MaterialSelectionDropDown.Value == "TangoPlus"
                if app.DigitalMaterialDropDown.Value == "N/A"
                    app.YoungsModuluskPaEditField.Value = 970;
                elseif app.DigitalMaterialDropDown.Value == "DM40"
                    app.YoungsModuluskPaEditField.Value = 1347.5;
                elseif app.DigitalMaterialDropDown.Value == "DM50"
                    app.YoungsModuluskPaEditField.Value = 1706.9;
                elseif app.DigitalMaterialDropDown.Value == "DM60"
                    app.YoungsModuluskPaEditField.Value = 2159.1;
                elseif app.DigitalMaterialDropDown.Value == "DM70"
                    app.YoungsModuluskPaEditField.Value = 2731.1;
                end
                app.PossionRatioEditField.Value = 0.49;
                app.Densitygcm3EditField.Value = 1.16;
            else
                app.DigitalMaterialDropDown.Value = "N/A";
            end

        end

        % Value changed function: PossionRatioEditField
        function PossionRatioEditFieldValueChanged(app, event)

            value = app.PossionRatioEditField.Value;
        end

        % Value changed function: Densitygcm3EditField
        function Densitygcm3EditFieldValueChanged(app, event)

            value = app.Densitygcm3EditField.Value;
        end

        % Value changed function: YoungsModuluskPaEditField
        function YoungsModuluskPaEditFieldValueChanged(app, event)
            
            if app.MaterialSelectionDropDown.Value == "Agilus30"
                if app.DigitalMaterialDropDown.Value == "N/A"
                    app.YoungsModuluskPaEditField.Value = 450;
                elseif app.DigitalMaterialDropDown.Value == "DM40"
                    app.YoungsModuluskPaEditField.Value = 550.1;
                elseif app.DigitalMaterialDropDown.Value == "DM50"
                    app.YoungsModuluskPaEditField.Value = 571.5;
                elseif app.DigitalMaterialDropDown.Value == "DM60"
                    app.YoungsModuluskPaEditField.Value = 898.1;
                elseif app.DigitalMaterialDropDown.Value == "DM70"
                    app.YoungsModuluskPaEditField.Value = 1699.1;
                end   
            elseif app.MaterialSelectionDropDown.Value == "TangoPlus"
                if app.DigitalMaterialDropDown.Value == "N/A"
                    app.YoungsModuluskPaEditField.Value = 970;
                elseif app.DigitalMaterialDropDown.Value == "DM40"
                    app.YoungsModuluskPaEditField.Value = 1347.5;
                elseif app.DigitalMaterialDropDown.Value == "DM50"
                    app.YoungsModuluskPaEditField.Value = 1706.9;
                elseif app.DigitalMaterialDropDown.Value == "DM60"
                    app.YoungsModuluskPaEditField.Value = 2159.1;
                elseif app.DigitalMaterialDropDown.Value == "DM70"
                    app.YoungsModuluskPaEditField.Value = 2731.1;
                end
            else
                app.DigitalMaterialDropDown.Value = "N/A";
            end
        end

        % Button pushed function: AnalyzeResultButton
        function AnalyzeResultButtonPushed(app, event)
             h = waitbar(0.5,'Theoretical Modeling Analyzing');
            global PressureData TheoreticalDeflection InnerRadiusData AverageRadiusData OuterRadiusData R1Data R2Data PHI1Data PHI2Data
            [PressureData, TheoreticalDeflection, InnerRadiusData, AverageRadiusData, OuterRadiusData, R1Data, R2Data, PHI1Data, PHI2Data] = TheoreticalModel(app.EffectiveLengthmmEditField.Value, app.AverageRadiusmmEditField.Value, app.BellowNumberEditField.Value, app.RadiusRatioEditField.Value*app.AverageRadiusmmEditField.Value, app.WallThicknesstmmEditField.Value ,app.YoungsModuluskPaEditField.Value);
            UpdateSchematicDiagram = imread("DeformedSchematicDiagram.jpg");
            app.Image2.ImageSource = repmat(UpdateSchematicDiagram, 1);
            waitbar(1,h,'Finished');
            app.PressureSliderkPa.Limits = [PressureData(1) PressureData(length(PressureData))];
            app.PressureSliderkPa.Value = PressureData(length(PressureData)); 
            app.EditField.Value = PressureData(length(PressureData));
            app.CorrespondingAngularDeflectiondegreeEditField.Value = TheoreticalDeflection(length(TheoreticalDeflection))*180/pi;
            delete(h);
        end

        % Value changing function: PressureSliderkPa
        function PressureSliderkPaValueChanging(app, event)
            changingValue = event.Value;
            global PressureData TheoreticalDeflection InnerRadiusData AverageRadiusData OuterRadiusData R1Data R2Data PHI1Data PHI2Data
            i = 1;
            if changingValue == PressureData(1)
                i = 1;
            else 
                while changingValue > PressureData(i)
                    i = i+1;
                end
                i = i-1;
            end
            
            if changingValue <= 1/2*(PressureData(i) + PressureData(i+1))
                DeformedSchematicDiagram(app.EffectiveLengthmmEditField.Value, app.AverageRadiusmmEditField.Value, app.BellowNumberEditField.Value, app.RadiusRatioEditField.Value * app.AverageRadiusmmEditField.Value, app.WallThicknesstmmEditField.Value, InnerRadiusData(i), AverageRadiusData(i), OuterRadiusData(i), R1Data(i), R2Data(i), PHI1Data(i), PHI2Data(i), TheoreticalDeflection(i));
                app.EditField.Value = PressureData(i);
                app.CorrespondingAngularDeflectiondegreeEditField.Value = TheoreticalDeflection(i)*180/pi;
                UpdateSchematicDiagram = imread("DeformedSchematicDiagram.jpg");
                app.Image2.ImageSource = repmat(UpdateSchematicDiagram, 1);
            else 
                DeformedSchematicDiagram(app.EffectiveLengthmmEditField.Value, app.AverageRadiusmmEditField.Value, app.BellowNumberEditField.Value, app.RadiusRatioEditField.Value * app.AverageRadiusmmEditField.Value, app.WallThicknesstmmEditField.Value, InnerRadiusData(i+1), AverageRadiusData(i+1), OuterRadiusData(i+1), R1Data(i+1), R2Data(i+1), PHI1Data(i+1), PHI2Data(i+1), TheoreticalDeflection(i+1));                
                app.EditField.Value = PressureData(i+1);
                app.CorrespondingAngularDeflectiondegreeEditField.Value = TheoreticalDeflection(i+1)*180/pi;
                UpdateSchematicDiagram = imread("DeformedSchematicDiagram.jpg");
                app.Image2.ImageSource = repmat(UpdateSchematicDiagram, 1);
            end
            
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            import com.comsol.model.*
            import com.comsol.model.util.*
            h = waitbar(0, 'COMSOL Model of Deflection is Loading');
            model = mphopen('BellowSPADeflection.mph', 'Deflection');
            waitbar(1,h, 'COMSOL Model Loading Completes');
            delete(h);
            
            h = waitbar(0,'Pressure Control Simulation Running');
            EffectiveLength = app.EffectiveLengthmmEditField.Value;
            AverageRadius = app.AverageRadiusmmEditField.Value;
            BellowNum = app.BellowNumberEditField.Value;
            InnerRadius = app.RadiusRatioEditField.Value * AverageRadius;
            WallThickness = app.WallThicknesstmmEditField.Value;
            YoungModulus = app.YoungsModuluskPaEditField.Value;
            PossionRatio = app.PossionRatioEditField.Value;
            Density = app.Densitygcm3EditField.Value;
            TargetPressure = app.TargetPressurePkPaEditField.Value;
            model.param.set('L', string(EffectiveLength)+'[mm]');
            model.param.set('Rm', string(AverageRadius)+'[mm]');
            model.param.set('N', string(BellowNum));
            model.param.set('Ri', string(InnerRadius)+'[mm]');
            model.param.set('t', string(WallThickness)+'[mm]');
            model.param.set('Es', string(YoungModulus)+'[kPa]');
            model.param.set('Nu', string(PossionRatio));
            model.param.set('miu', string(Density)+'[g/cm^3]');
            model.param.set('Pf', string(TargetPressure)+'[kPa]');
            if TargetPressure > 0.1
                model.param.set('Ps', num2str(0.1)+"[kPa]");
            else
                model.param.set('Ps', num2str(TargetPressure/2)+"[kPa]");
            end

            model.geom('geom1').runAll;
            model.geom('geom1').run;
            
            meshsize = ["Extremely fine", "Extra fine", "Finer", "Fine", "Normal", "Coarse", "Coarser", "Extra coarse", "Extremely coarse"];
            meshstring = app.MeshElementSizeDropDown.Value;
            i = 1;
            while(1)
                if (meshsize(i) ~= meshstring)
                    i = i + 1;
                else
                    size = i;
                    break
                end
            end
            
            mesh1 = model.component('comp1').mesh('mesh1').autoMeshSize(size);
            mesh1.run;
%             k = mphmesh(model)

                
            waitbar(0.5, h, 'Pressure Control Simulation Working');
            model.component('comp1').physics('solid').feature('ge1').active(false);
            model.sol('sol1').runAll;
            if TargetPressure > 0.5
                
                model.result('pg1').set('solnum', round(TargetPressure/0.1) + 1);
                model.result('pg1').run;

                model.result('pg3').set('solnum', round(TargetPressure/0.1) + 1);
                model.result('pg3').run;

            else
                model.result('pg1').set('solnum', 3);
                model.result('pg1').run;

                model.result('pg3').setIndex('solnum', 3);
                model.result('pg3').run;

            end
            model.result.table.create('tbl1', 'Table');
            model.result.table('tbl1').comments('PressureControlAngle');
            model.result.numerical('av1').set('table', 'tbl1');
            model.result.numerical('av1').setResult;
            

            tab1 = mphtable(model,'tbl1');
            PressureControl = tab1.data(:,1);
            PressureControlAngle = tab1.data(:,2);
            waitbar(1,h,'Pressure Control Simulation Complete');
            figure("Name",'Pressure vs. AngularDeflection', "NumberTitle","off")
            plot(PressureControl,PressureControlAngle,'-bx');
            hold on
            title('Pressure vs. Angular Deflection');
            xlabel('Pressure [Pa]');
            ylabel('Angular Deflection [degree]');
            hold off
            
            figure("Name",'Stress Plot', "NumberTitle","off")
            mphplot(model,'pg1','rangenum',1) 
            view([0,-10,0]);

            figure("Name",'Strain Plot', "NumberTitle","off")
            mphplot(model,'pg3','rangenum',1)
            view([0,-10,0]);

            delete(h);
            if ~isempty(mphshowerrors(model))
                uialert(app.UIFigure, 'Failed to converge to a solution','Warning','Icon','error');
            end

        ModelUtil.remove('Deflection');
        end

        % Button pushed function: AnalyzeButton_2
        function AnalyzeButton_2Pushed(app, event)
            import com.comsol.model.*
            import com.comsol.model.util.*
            h = waitbar(0, 'COMSOL Model of Deflection is Loading');
            model = mphload('BellowSPADeflection.mph', 'Deflection');
            waitbar(1,h, 'COMSOL Model Loading Completes');
            delete(h);
            
            h = waitbar(0,'Displacement Control Simulation Running');
            EffectiveLength = app.EffectiveLengthmmEditField.Value;
            AverageRadius = app.AverageRadiusmmEditField.Value;
            BellowNum = app.BellowNumberEditField.Value;
            InnerRadius = app.RadiusRatioEditField.Value * AverageRadius;
            WallThickness = app.WallThicknesstmmEditField.Value;
            YoungModulus = app.YoungsModuluskPaEditField.Value;
            PossionRatio = app.PossionRatioEditField.Value;
            Density = app.Densitygcm3EditField.Value;
            TargetAngularDeflection = app.TargetAngularDeflectiondegreeEditField.Value;
            model.param.set('L', string(EffectiveLength)+'[mm]');
            model.param.set('Rm', string(AverageRadius)+'[mm]');
            model.param.set('N', string(BellowNum));
            model.param.set('Ri', string(InnerRadius)+'[mm]');
            model.param.set('t', string(WallThickness)+'[mm]');
            model.param.set('Es', string(YoungModulus)+'[kPa]');
            model.param.set('Nu', string(PossionRatio));
            model.param.set('miu', string(Density)+'[g/cm^3]');
            model.param.set('Theta_f', string(TargetAngularDeflection)+'[kPa]');
           
            model.geom('geom1').runAll;
            model.geom('geom1').run;
            
            meshsize = ["Extremely fine", "Extra fine", "Finer", "Fine", "Normal", "Coarse", "Coarser", "Extra coarse", "Extremely coarse"];
            meshstring = app.MeshElementSizeDropDown.Value
            i = 1;
            while(1)
                if (meshsize(i) ~= meshstring)
                    i = i + 1;
                else
                    size = i
                    break
                end
            end
            
            mesh1 = model.component('comp1').mesh('mesh1').autoMeshSize(size);
            mesh1.run;
%             mphmesh(model)
            
            waitbar(0.5,h,'Displacement Control Simulation Working');
            model.component('comp1').physics('solid').feature('ge1').active(true);
            model.sol('sol2').runAll;
            model.result('pg5').set('solnum', round(TargetAngularDeflection/1) + 1);
            model.result('pg5').run;

            model.result('pg7').set('solnum', round(TargetAngularDeflection/1) + 1);
            model.result('pg7').run;

            
            model.result.table.create('tbl2', 'Table');
            model.result.table('tbl2').comments('DisplacementControlPressure');
            model.result.numerical('gev1').set('table', 'tbl2');
            model.result.numerical('gev1').setResult;

            tab2 = mphtable(model,'tbl2');
            DisplacementControl = tab2.data(:,1);
            DisplacementControlPressure = tab2.data(:,2);
            waitbar(1,h,'Displacement Control Simulation Complete');
            figure("Name",'AngularDeflection vs. Pressure', "NumberTitle","off")
            plot(DisplacementControl,DisplacementControlPressure,'-bx');
            hold on
            title('Angular Deflection vs. Pressure');
            ylabel('Pressure [Pa]');
            xlabel('Angular Deflection [degree]');
            hold off
            
            figure("Name",'Stress Plot', "NumberTitle","off")
            mphplot(model,'pg5','rangenum',1) 
            view([0,-10,0]);

            figure("Name",'Strain Plot', "NumberTitle","off")
            mphplot(model,'pg7','rangenum',1)
            view([0,-10,0]);

            delete(h);
           
        if ~isempty(mphshowerrors(model))
                uialert(app.UIFigure, 'Failed to converge to a solution','Warning','Icon','error');
        end
        ModelUtil.remove('Deflection');
        end

        % Button pushed function: AnalyzeButton_3
        function AnalyzeButton_3Pushed(app, event)
            import com.comsol.model.*
            import com.comsol.model.util.*
            h = waitbar(0, 'COMSOL Model of Force is Loading');
            model = mphopen('BellowSPABlockForce.mph', 'Force');
            waitbar(1,h, 'COMSOL Model Loading Completes');
            delete(h);
            
            h = waitbar(0,'Pressure Control Simulation Running');
            EffectiveLength = app.EffectiveLengthmmEditField.Value;
            AverageRadius = app.AverageRadiusmmEditField.Value;
            BellowNum = app.BellowNumberEditField.Value;
            InnerRadius = app.RadiusRatioEditField.Value * AverageRadius;
            WallThickness = app.WallThicknesstmmEditField.Value;
            YoungModulus = app.YoungsModuluskPaEditField.Value;
            PossionRatio = app.PossionRatioEditField.Value;
            Density = app.Densitygcm3EditField.Value;
            TargetPressure = app.TargetPressurePkPaEditField_2.Value;
            model.param.set('L', string(EffectiveLength)+'[mm]');
            model.param.set('Rm', string(AverageRadius)+'[mm]');
            model.param.set('N', string(BellowNum));
            model.param.set('Ri', string(InnerRadius)+'[mm]');
            model.param.set('t', string(WallThickness)+'[mm]');
            model.param.set('Es', string(YoungModulus)+'[kPa]');
            model.param.set('Nu', string(PossionRatio));
            model.param.set('miu', string(Density)+'[g/cm^3]');
            model.param.set('Pf', string(TargetPressure)+'[kPa]');
            if TargetPressure > 0.1
                model.param.set('Ps', num2str(0.1)+"[kPa]");
            else
                model.param.set('Ps', num2str(TargetPressure/4)+"[kPa]");
            end
            model.component('comp1').geom('geom1').feature('fin').label('Form Assembly');
            model.component('comp1').geom('geom1').feature('fin').set('action', 'assembly');
            model.component('comp1').geom('geom1').feature('fin').set('imprint', true);
            model.component('comp1').geom('geom1').feature('fin').set('pairtype', 'contact');
            model.component('comp1').geom('geom1').run;
            model.component('comp1').geom('geom1').run('fin');
            
            meshsize = ["Extremely fine", "Extra fine", "Finer", "Fine", "Normal", "Coarse", "Coarser", "Extra coarse", "Extremely coarse"];
            meshstring = app.MeshElementSizeDropDown_2.Value
            i = 1;
            while(1)
                if (meshsize(i) ~= meshstring)
                    i = i + 1;
                else
                    size = i
                    break
                end
            end
            
            mesh1 = model.component('comp1').mesh('mesh1').autoMeshSize(size);
            mesh1.run;
%             mphmesh(model)
            
            waitbar(0.5,h,'Pressure Control Simulation Working');
            model.sol('sol1').runAll;
%             model.result('pg1').feature('surf1').set('looplevel',  num2str(Step + 1));
            if TargetPressure > 0.1
%                     model.result('pg1').setIndex('looplevel', num2str(round(TargetPressure/0.1) + 1), 0);
                model.result('pg1').set('solnum', round(TargetPressure/0.1) + 1);
                model.result('pg1').run;

%                     model.result('pg3').setIndex('looplevel', num2str(round(TargetPressure/0.1) + 1), 0);
                model.result('pg3').set('solnum', round(TargetPressure/0.1) + 1);
                model.result('pg3').run;

            else
                model.result('pg1').set('solnum', 5);
                model.result('pg1').run;

                model.result('pg3').set('solnum', 5);
                model.result('pg3').run;

            end
            
            model.result.table.create('tbl1', 'Table');
            model.result.table('tbl1').comments('PressureControlForce');
            model.result.numerical('int1').set('table', 'tbl1');
            model.result.numerical('int1').setResult;
            
            tab1 = mphtable(model,'tbl1');
            Pressure = tab1.data(:,1);
            BlockForce = tab1.data(:,2);
            waitbar(1,h,'Pressure Control Simulation Complete');
            figure("Name",'Pressure vs. Blocked Force', "NumberTitle","off")
            plot(Pressure,BlockForce*101.97,'-bx');
            hold on
            title('Pressure vs. Blocked Force');
            xlabel('Pressure [Pa]');
            ylabel('Blocked Force [g]');
            hold off

            figure("Name",'Stress Plot', "NumberTitle","off")
            mphplot(model,'pg1','rangenum',1) 
            view([0,-10,0]);

            figure("Name",'Strain Plot', "NumberTitle","off")
            mphplot(model,'pg3','rangenum',1)
            view([0,-10,0]);

            delete(h);
            
        if ~isempty(mphshowerrors(model))
                uialert(app.UIFigure, 'Failed to converge to a solution','Warning','Icon','error');
        end
        ModelUtil.remove('Force');
        end

        % Value changed function: DigitalMaterialDropDown
        function DigitalMaterialDropDownValueChanged(app, event)
            value = app.DigitalMaterialDropDown.Value;
            if app.MaterialSelectionDropDown.Value == "Agilus30"
                if value == "N/A"
                    app.YoungsModuluskPaEditField.Value = 450;
                elseif value == "DM40"
                    app.YoungsModuluskPaEditField.Value = 550.1;
                elseif value == "DM50"
                    app.YoungsModuluskPaEditField.Value = 571.5;
                elseif value == "DM60"
                    app.YoungsModuluskPaEditField.Value = 898.1;
                elseif value == "DM70"
                    app.YoungsModuluskPaEditField.Value = 1699.1;
                end
                app.PossionRatioEditField.Value = 0.49;
                app.Densitygcm3EditField.Value = 1.16;
            elseif app.MaterialSelectionDropDown.Value == "TangoPlus"
                if value == "N/A"
                    app.YoungsModuluskPaEditField.Value = 970;
                elseif value == "DM40"
                    app.YoungsModuluskPaEditField.Value = 1347.5;
                elseif value == "DM50"
                    app.YoungsModuluskPaEditField.Value = 1706.9;
                elseif value == "DM60"
                    app.YoungsModuluskPaEditField.Value = 2159.1;
                elseif value == "DM70"
                    app.YoungsModuluskPaEditField.Value = 2731.1;
                end
                app.PossionRatioEditField.Value = 0.49;
                app.Densitygcm3EditField.Value = 1.16;
                
            else
                value = "N/A";
            end
        end

        % Button pushed function: DesignUpdateButton
        function DesignUpdateButtonPushed(app, event)
            L = app.EffectiveLengthmmEditField.Value;
            rm = app.AverageRadiusmmEditField.Value;
            N = app.BellowNumberEditField.Value;
            ri = app.RadiusRatioEditField.Value*rm;
            ro = 2*rm - ri;
            t = app.WallThicknesstmmEditField.Value;
            
            if ((L > 0) && (rm > 0) && (N >= 0) && (ri > 0) && (t > 0))
                if (floor(N) == N)
                    if (L/N/4 > t)
                        if (ri > 1.5)
                            if ((ro - ri) >= L/N/2)
                                SchematicDiagram(app.EffectiveLengthmmEditField.Value, app.AverageRadiusmmEditField.Value, app.BellowNumberEditField.Value, app.RadiusRatioEditField.Value * app.AverageRadiusmmEditField.Value, app.WallThicknesstmmEditField.Value);
                                UpdateSchematicDiagram = imread("SchematicDiagram.jpg");
                                app.Image2.ImageSource = repmat(UpdateSchematicDiagram, 1);
                            else
                                uialert(app.UIFigure, 'Radius ratio is too large / bellow number is too small.','Warning','Icon','error');
                            end
                        else
                            uialert(app.UIFigure, 'Radius ratio is too small.','Warning','Icon','error');
                        end
                    else
                        uialert(app.UIFigure, 'Bellow number is too large / wall thickness is too large.','Warning','Icon','error');
                    end
                else
                    uialert(app.UIFigure, 'Bellow number should be integer.','Warning','Icon','error');
                end
            else
                uialert(app.UIFigure, 'Invalid input (all input should be positive).','Warning','Icon','error');
            end
            
        end

        % Value changed function: WallThicknesstmmEditField
        function WallThicknesstmmEditFieldValueChanged(app, event)
            value = app.WallThicknesstmmEditField.Value;
            
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            app.EditField.Value = round(value*10)/10;
            
            global PressureData TheoreticalDeflection InnerRadiusData AverageRadiusData OuterRadiusData R1Data R2Data PHI1Data PHI2Data
            
            if (app.EditField.Value > PressureData(length(PressureData)))
                app.EditField.Value = PressureData(length(PressureData));
            elseif (app.EditField.Value < PressureData(1))
                app.EditField.Value = PressureData(1);
            end
            app.PressureSliderkPa.Value = app.EditField.Value;
            
            i = 1;
            if app.PressureSliderkPa.Value == PressureData(1)
                i = 1;
            else 
                while app.PressureSliderkPa.Value > PressureData(i)
                    i = i+1;
                end
                i = i-1;
            end
            
            if app.PressureSliderkPa.Value <= 1/2*(PressureData(i) + PressureData(i+1))
                DeformedSchematicDiagram(app.EffectiveLengthmmEditField.Value, app.AverageRadiusmmEditField.Value, app.BellowNumberEditField.Value, app.RadiusRatioEditField.Value * app.AverageRadiusmmEditField.Value, app.WallThicknesstmmEditField.Value, InnerRadiusData(i), AverageRadiusData(i), OuterRadiusData(i), R1Data(i), R2Data(i), PHI1Data(i), PHI2Data(i), TheoreticalDeflection(i));
                app.EditField.Value = PressureData(i);
                app.CorrespondingAngularDeflectiondegreeEditField.Value = TheoreticalDeflection(i)*180/pi;
                UpdateSchematicDiagram = imread("DeformedSchematicDiagram.jpg");
                app.Image2.ImageSource = repmat(UpdateSchematicDiagram, 1);
            else 
                DeformedSchematicDiagram(app.EffectiveLengthmmEditField.Value, app.AverageRadiusmmEditField.Value, app.BellowNumberEditField.Value, app.RadiusRatioEditField.Value * app.AverageRadiusmmEditField.Value, app.WallThicknesstmmEditField.Value, InnerRadiusData(i+1), AverageRadiusData(i+1), OuterRadiusData(i+1), R1Data(i+1), R2Data(i+1), PHI1Data(i+1), PHI2Data(i+1), TheoreticalDeflection(i+1));                
                app.EditField.Value = PressureData(i+1);
                app.CorrespondingAngularDeflectiondegreeEditField.Value = TheoreticalDeflection(i+1)*180/pi;
                UpdateSchematicDiagram = imread("DeformedSchematicDiagram.jpg");
                app.Image2.ImageSource = repmat(UpdateSchematicDiagram, 1);
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.EffectiveLengthmmEditField.Value = 66;
            app.AverageRadiusmmEditField.Value = 6;
            app.BellowNumberEditField.Value = 6;
            app.RadiusRatioEditField.Value = 1/3;
            app.WallThicknesstmmEditField.Value = 1.5;
            SchematicDiagram(app.EffectiveLengthmmEditField.Value, app.AverageRadiusmmEditField.Value, app.BellowNumberEditField.Value, app.RadiusRatioEditField.Value * app.AverageRadiusmmEditField.Value, app.WallThicknesstmmEditField.Value);
            UpdateSchematicDiagram = imread("SchematicDiagram.jpg");
            app.Image2.ImageSource = repmat(UpdateSchematicDiagram, 1);
        end

        % Button pushed function: ExportGeometryButton
        function ExportGeometryButtonPushed(app, event)
            model = mphload('BellowSPADeflection.mph');
            EffectiveLength = app.EffectiveLengthmmEditField.Value;
            AverageRadius = app.AverageRadiusmmEditField.Value;
            BellowNum = app.BellowNumberEditField.Value;
            InnerRadius = app.RadiusRatioEditField.Value * AverageRadius;
            WallThickness = app.WallThicknesstmmEditField.Value;
            YoungModulus = app.YoungsModuluskPaEditField.Value;
            PossionRatio = app.PossionRatioEditField.Value;
            Density = app.Densitygcm3EditField.Value;
            model.param.set('L', string(EffectiveLength)+'[mm]');
            model.param.set('Rm', string(AverageRadius)+'[mm]');
            model.param.set('N', string(BellowNum));
            model.param.set('Ri', string(InnerRadius)+'[mm]');
            model.param.set('t', string(WallThickness)+'[mm]');
            model.param.set('Es', string(YoungModulus)+'[kPa]');
            model.param.set('Nu', string(PossionRatio));
            model.param.set('miu', string(Density)+'[g/cm^3]');
            model.geom('geom1').runAll;
            model.geom('geom1').run;
            model.component('comp1').geom('geom1').run('fin');
            model.component('comp1').geom('geom1').export.setSTLFormat('binary');
            model.component('comp1').geom('geom1').export.selection.init(3);
            model.component('comp1').geom('geom1').export.selection.set('fin', 1);
            model.component('comp1').geom('geom1').export('SPA_Framer_Connector.stl');
            model.component('comp1').geom('geom1').export.setSTLFormat('binary');
            model.component('comp1').geom('geom1').export.selection.init(3);
            model.component('comp1').geom('geom1').export.selection.set('fin', 2);
            model.component('comp1').geom('geom1').export('SPA_Soft_Part.stl');
            model.component('comp1').geom('geom1').export.setSTLFormat('binary');
            model.component('comp1').geom('geom1').export.selection.init(3);
            model.component('comp1').geom('geom1').export.selection.set('fin', [3 4 5]);
            model.component('comp1').geom('geom1').export('SPA_End_Cap.stl');
            uialert(app.UIFigure, 'All components has been exported','Successful','Icon','success');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GeometricParametersPanel = uipanel(app.UIFigure);
            app.GeometricParametersPanel.Title = 'Geometric Parameters';
            app.BellowGeometryPanel = uipanel(app.GeometricParametersPanel);
            app.BellowGeometryPanel.Title = 'Bellow Geometry';
            app.BellowNumberLabel = uilabel(app.BellowGeometryPanel);
            app.BellowNumberLabel.Text = 'Bellow Number';
            app.BellowNumberEditField = uieditfield(app.BellowGeometryPanel, 'numeric');
            app.BellowNumberEditField.ValueChangedFcn = createCallbackFcn(app, @BellowNumberEditFieldValueChanged, true);
            app.BellowNumberEditField.Value = 6;
            app.RadiusRatioLabel = uilabel(app.BellowGeometryPanel);
            app.RadiusRatioLabel.Text = 'Radius Ratio';
            app.RadiusRatioEditField = uieditfield(app.BellowGeometryPanel, 'numeric');
            app.RadiusRatioEditField.ValueChangedFcn = createCallbackFcn(app, @RadiusRatioEditFieldValueChanged, true);
            app.RadiusRatioEditField.Value = 0.333333333333333;
            app.WallThicknesstmmLabel = uilabel(app.BellowGeometryPanel);
            app.WallThicknesstmmLabel.Text = 'Wall Thickness t [mm]';
            app.WallThicknesstmmEditField = uieditfield(app.BellowGeometryPanel, 'numeric');
            app.WallThicknesstmmEditField.ValueChangedFcn = createCallbackFcn(app, @WallThicknesstmmEditFieldValueChanged, true);
            app.WallThicknesstmmEditField.Value = 1.5;
            app.GeneralGeometryPanel = uipanel(app.GeometricParametersPanel);
            app.GeneralGeometryPanel.Title = 'General Geometry';
            app.EffectiveLengthmmLabel = uilabel(app.GeneralGeometryPanel);
            app.EffectiveLengthmmLabel.Text = 'Effective Length [mm]';
            app.EffectiveLengthmmEditField = uieditfield(app.GeneralGeometryPanel, 'numeric');
            app.EffectiveLengthmmEditField.ValueChangedFcn = createCallbackFcn(app, @EffectiveLengthmmEditFieldValueChanged, true);
            app.EffectiveLengthmmEditField.Value = 66;
            app.AverageRadiusmmLabel = uilabel(app.GeneralGeometryPanel);
            app.AverageRadiusmmLabel.Text = 'Average Radius [mm]';
            app.AverageRadiusmmEditField = uieditfield(app.GeneralGeometryPanel, 'numeric');
            app.AverageRadiusmmEditField.ValueChangedFcn = createCallbackFcn(app, @AverageRadiusmmEditFieldValueChanged, true);
            app.AverageRadiusmmEditField.Value = 6;
            app.DesignUpdateButton = uibutton(app.GeometricParametersPanel, 'push');
            app.DesignUpdateButton.ButtonPushedFcn = createCallbackFcn(app, @DesignUpdateButtonPushed, true);
            app.DesignUpdateButton.Text = 'Design Update';
            app.ResetButton = uibutton(app.GeometricParametersPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.MaterialPropertiesPanel = uipanel(app.UIFigure);
            app.MaterialPropertiesPanel.Title = 'Material Properties';
            app.MaterialSelectionDropDownLabel = uilabel(app.MaterialPropertiesPanel);
            app.MaterialSelectionDropDownLabel.Text = 'Material Selection';
            app.MaterialSelectionDropDown = uidropdown(app.MaterialPropertiesPanel);
            app.MaterialSelectionDropDown.Items = {'Agilus30', 'TangoPlus', 'Manually Input'};
            app.MaterialSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @MaterialSelectionDropDownValueChanged, true);
            app.MaterialSelectionDropDown.Value = 'Agilus30';
            app.DigitalMaterialDropDownLabel = uilabel(app.MaterialPropertiesPanel);
            app.DigitalMaterialDropDownLabel.Text = 'Digital Material';
            app.DigitalMaterialDropDown = uidropdown(app.MaterialPropertiesPanel);
            app.DigitalMaterialDropDown.Items = {'N/A', 'DM40', 'DM50', 'DM60', 'DM70'};
            app.DigitalMaterialDropDown.ValueChangedFcn = createCallbackFcn(app, @DigitalMaterialDropDownValueChanged, true);
            app.DigitalMaterialDropDown.Value = 'N/A';
            app.YoungsModuluskPaLabel = uilabel(app.MaterialPropertiesPanel);
            app.YoungsModuluskPaLabel.Text = 'Young''s Modulus [kPa]';
            app.YoungsModuluskPaEditField = uieditfield(app.MaterialPropertiesPanel, 'numeric');
            app.YoungsModuluskPaEditField.ValueChangedFcn = createCallbackFcn(app, @YoungsModuluskPaEditFieldValueChanged, true);
            app.YoungsModuluskPaEditField.Value = 450;
            app.PossionRatioEditFieldLabel = uilabel(app.MaterialPropertiesPanel);
            app.PossionRatioEditFieldLabel.Text = 'Possion Ratio';
            app.PossionRatioEditField = uieditfield(app.MaterialPropertiesPanel, 'numeric');
            app.PossionRatioEditField.ValueChangedFcn = createCallbackFcn(app, @PossionRatioEditFieldValueChanged, true);
            app.PossionRatioEditField.Value = 0.49;
            app.Densitygcm3EditFieldLabel = uilabel(app.MaterialPropertiesPanel);
            app.Densitygcm3EditFieldLabel.Text = 'Density [g/cm^3]';
            app.Densitygcm3EditField = uieditfield(app.MaterialPropertiesPanel, 'numeric');
            app.Densitygcm3EditField.ValueChangedFcn = createCallbackFcn(app, @Densitygcm3EditFieldValueChanged, true);
            app.Densitygcm3EditField.Value = 1.16;
            app.TheoreticalModelingPanel = uipanel(app.UIFigure);
            app.TheoreticalModelingPanel.Title = 'Theoretical Modeling';
            app.AnalyzeResultButton = uibutton(app.TheoreticalModelingPanel, 'push');
            app.AnalyzeResultButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeResultButtonPushed, true);
            app.AnalyzeResultButton.Text = 'Analyze Result';
            app.PressureSliderkPaLabel = uilabel(app.TheoreticalModelingPanel);
            app.PressureSliderkPaLabel.Text = 'Pressure Slider [kPa]';
            app.PressureSliderkPa = uislider(app.TheoreticalModelingPanel);
            app.PressureSliderkPa.Limits = [0 10];
            app.PressureSliderkPa.ValueChangingFcn = createCallbackFcn(app, @PressureSliderkPaValueChanging, true);
            app.EditField = uieditfield(app.TheoreticalModelingPanel, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.CorrespondingAngularDeflectiondegreeLabel = uilabel(app.TheoreticalModelingPanel);
            app.CorrespondingAngularDeflectiondegreeLabel.Text = {'Corresponding Angular Deflection'; '[degree]'};
            app.CorrespondingAngularDeflectiondegreeEditField = uieditfield(app.TheoreticalModelingPanel, 'numeric');
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'GeometryParameter.jpg';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'InitialSchematicDiagram.jpg';
            app.FEAModelingPanel = uipanel(app.UIFigure);
            app.FEAModelingPanel.Title = 'FEA Modeling';
            app.DeflectionAnlysisPanel = uipanel(app.FEAModelingPanel);
            app.DeflectionAnlysisPanel.Title = 'Deflection Anlysis';
            app.TargetPressurePkPaLabel = uilabel(app.DeflectionAnlysisPanel);
            app.TargetPressurePkPaLabel.Text = 'Target Pressure P [kPa]';
            app.TargetPressurePkPaEditField = uieditfield(app.DeflectionAnlysisPanel, 'numeric');
            app.TargetAngularDeflectiondegreeLabel = uilabel(app.DeflectionAnlysisPanel);
            app.TargetAngularDeflectiondegreeLabel.Text = {'Target Angular Deflection'; '[degree]'};
            app.TargetAngularDeflectiondegreeEditField = uieditfield(app.DeflectionAnlysisPanel, 'numeric');
            app.AnalyzeButton = uibutton(app.DeflectionAnlysisPanel, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.AnalyzeButton_2 = uibutton(app.DeflectionAnlysisPanel, 'push');
            app.AnalyzeButton_2.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButton_2Pushed, true);
            app.AnalyzeButton_2.Text = 'Analyze';
            app.MeshElementSizeDropDownLabel = uilabel(app.DeflectionAnlysisPanel);
            app.MeshElementSizeDropDownLabel.Text = 'Mesh Element Size';
            app.MeshElementSizeDropDown = uidropdown(app.DeflectionAnlysisPanel);
            app.MeshElementSizeDropDown.Items = {'Extremely fine', 'Extra fine', 'Finer', 'Fine', 'Normal', 'Coarse', 'Coarser', 'Extra coarse', 'Extremely coarse'};
            app.MeshElementSizeDropDown.Value = 'Finer';
            app.ForceAnlysisPanel = uipanel(app.FEAModelingPanel);
            app.ForceAnlysisPanel.Title = 'Force Anlysis';
            app.TargetPressurePkPaLabel_2 = uilabel(app.ForceAnlysisPanel);
            app.TargetPressurePkPaLabel_2.Text = 'Target Pressure P [kPa]';
            app.TargetPressurePkPaEditField_2 = uieditfield(app.ForceAnlysisPanel, 'numeric');
            app.AnalyzeButton_3 = uibutton(app.ForceAnlysisPanel, 'push');
            app.AnalyzeButton_3.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButton_3Pushed, true);
            app.AnalyzeButton_3.Text = 'Analyze';
            app.MeshElementSizeDropDown_2Label = uilabel(app.ForceAnlysisPanel);
            app.MeshElementSizeDropDown_2Label.Text = 'Mesh Element Size';
            app.MeshElementSizeDropDown_2 = uidropdown(app.ForceAnlysisPanel);
            app.MeshElementSizeDropDown_2.Items = {'Extremely fine', 'Extra fine', 'Finer', 'Fine', 'Normal', 'Coarse', 'Coarser', 'Extra coarse', 'Extremely coarse'};
            app.MeshElementSizeDropDown_2.Value = 'Normal';
            app.ExportGeometryButton = uibutton(app.FEAModelingPanel, 'push');
            app.ExportGeometryButton.ButtonPushedFcn = createCallbackFcn(app, @ExportGeometryButtonPushed, true);
            app.ExportGeometryButton.Text = 'Export Geometry ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Toolbox

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end